classdef CascadiaGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CascadiaUIFigure               matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        LargestHabitatBonusesCheckBox  matlab.ui.control.CheckBox
        GameModeDropDown               matlab.ui.control.DropDown
        GameModeDropDownLabel          matlab.ui.control.Label
        ScoringObjectivesButton        matlab.ui.control.Button
        GameRulesButton                matlab.ui.control.Button
        EnvironmentLabel               matlab.ui.control.Label
        PlayerLabel                    matlab.ui.control.Label
        NumberofPlayersSpinner         matlab.ui.control.Spinner
        NumberofPlayersLabel           matlab.ui.control.Label
        DiscardWildlifeTokenButton     matlab.ui.control.Button
        StatusLabel                    matlab.ui.control.Label
        GameStatusLabel                matlab.ui.control.Label
        NatureTokensLabel              matlab.ui.control.Label
        Player4Label                   matlab.ui.control.Label
        Player3Label                   matlab.ui.control.Label
        Player2Label                   matlab.ui.control.Label
        Player1Label                   matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Label_1                        matlab.ui.control.Label
        ViewButton_4                   matlab.ui.control.Button
        ViewButton_3                   matlab.ui.control.Button
        ViewButton_2                   matlab.ui.control.Button
        ViewButton                     matlab.ui.control.Button
        PlaceTileTokenButton           matlab.ui.control.Button
        RotateTileButton               matlab.ui.control.Button
        SpendNatureTokenButton         matlab.ui.control.Button
        PlayerActionsLabel             matlab.ui.control.Label
        OverpopulationWipeButton       matlab.ui.control.Button
        NewGameButton                  matlab.ui.control.Button
        CascadiaLabel                  matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        CenterAxes                     matlab.ui.control.UIAxes
        EnvAxes                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties
        CascadiaGame (1,1) Game
        NPlayers (1,1) uint8
        CurrEnvView (1,1) uint8 % Which players env are we viewing
        CustomRules = ["A","A","A","A","A"];

        % Child dialogs
        ResultsDlg % Score table
        NatureTokenDlg % Nature Token options
        ObjectivesDlg % Wildlife score objectives
    end

    properties (Access = private, Constant)
        RulesPath = 'https://www.alderac.com/wp-content/uploads/2021/08/Cascadia-Rules.pdf';
        HexSideLength = 1;
    end
    
    methods (Access = private)
       
        function updatePlayerPanel(app)
            gameObj = app.CascadiaGame;
            availableActions = gameObj.Players(gameObj.PlayerTurn).AvailableActions;
            currPlayerEnv = gameObj.Players(gameObj.PlayerTurn).Environment;

            app.OverpopulationWipeButton.Enable = ismember(MovesEnum.OverpopulationWipe, availableActions);
            app.SpendNatureTokenButton.Enable = ismember(MovesEnum.SpendNatureToken, availableActions);
            app.RotateTileButton.Enable = ismember(MovesEnum.RotateTile, availableActions) && ...
                ~isempty(currPlayerEnv.PreviewTile);
            app.PlaceTileTokenButton.Enable = (ismember(MovesEnum.PlaceTile, availableActions) && ...
                ~isempty(currPlayerEnv.PreviewTile) ) || ...
                (ismember(MovesEnum.PlaceToken, availableActions) && ...
                ~isempty(currPlayerEnv.PreviewToken) && ~isempty(currPlayerEnv.PreviewTile));
            app.DiscardWildlifeTokenButton.Enable = ismember(MovesEnum.DiscardToken, availableActions);

            nPlayers = length(gameObj.Players);
            app.ViewButton.Enable = "on";
            app.ViewButton_2.Enable = "on";
            if nPlayers > 2
                app.ViewButton_3.Enable = "on";
                if nPlayers > 3
                    app.ViewButton_4.Enable = "on";
                end
            end

            updateStatusLabel(app);
            updateNatureTokenValues(app);
        end
        
        function switchView(app, playerId)
            viewEnv = app.CascadiaGame.Players(playerId).Environment;
            app.CurrEnvView = playerId;
            app.EnvAxes = drawEnvironment(viewEnv, app.EnvAxes, app.CascadiaGame.Players(playerId), app.HexSideLength);
        end

        function updateStatusLabel(app)
            app.StatusLabel.Text = app.CascadiaGame.StatusMsg;
        end
        
        function startNewTurnActions(app)                    
            % Enable buttons based on available moves
            updatePlayerPanel(app);

            if app.CascadiaGame.GameComplete              
                % Disable components until score dialog is closed
                setGameOptionsEnableStatus(app, false);
                app.ScoringObjectivesButton.Enable = false;
                app.NewGameButton.Text = 'New Game';
                app.NewGameButton.Enable = false;

                % Show score table dialog
                app.ResultsDlg = ScoreTableDialog(app, app.CascadiaGame.CurrentScores);

                % Show score objectives dialog
                app.ObjectivesDlg = ScoreObjectiveDialog(app, app.GameModeDropDown.Value, app.CustomRules);
            else
                app.CenterAxes = drawCenter(app.CascadiaGame, app.CenterAxes, app.HexSideLength);
                switchView(app, app.CascadiaGame.PlayerTurn);
            end
        end
        
        function updateNatureTokenValues(app)
            for i = 1:length(app.CascadiaGame.Players)
                labelName = ['Label_' num2str(i)];
                numTokens = num2str(app.CascadiaGame.Players(i).NatureTokens);
                app.(labelName).Text = num2str(numTokens);
            end
        end
        
        function populateGameModeValues(app)
            strArray = strings(1,GameModeEnum.NumGameModes);
            enumArray = GameModeEnum.empty;

            for i = 1:uint8(GameModeEnum.NumGameModes)
                strArray(i) = getString(GameModeEnum(i - 1));
                enumArray(i) = GameModeEnum(i - 1);
            end

            app.GameModeDropDown.Items = strArray;
            app.GameModeDropDown.ItemsData = enumArray;            
        end
                      
        
        function stringRules = numRules2Let(~, gameScoringRules)
            stringRules = strings(size(gameScoringRules));
            for i = 1:length(gameScoringRules)
                stringRules(i) = string(char(gameScoringRules(i) + 64));
            end         
        end
    end    
    
    methods (Access = public)        
        function spendNatureToken(app, natureTokenMetadata)       
            % Get results from nature token dialog
            app.CascadiaGame = playerAction(app.CascadiaGame, MovesEnum.SpendNatureToken, natureTokenMetadata);
            
            % Update visuals
            switchView(app, app.CascadiaGame.PlayerTurn);
            app.CenterAxes = drawCenter(app.CascadiaGame, app.CenterAxes, app.HexSideLength);
            updatePlayerPanel(app);
        end
        
        function setGameInteractivity(app, value)
            if ~value
                app.OverpopulationWipeButton.Enable = false;
                app.SpendNatureTokenButton.Enable = false;
                app.RotateTileButton.Enable = false;
                app.PlaceTileTokenButton.Enable = false;
                app.DiscardWildlifeTokenButton.Enable = false;
                app.CenterAxes.ButtonDownFcn = [];
                app.EnvAxes.ButtonDownFcn = [];
            elseif ~app.CascadiaGame.GameComplete
                app.CenterAxes.ButtonDownFcn = createCallbackFcn(app, @CenterAxesButtonDown, true);
                app.EnvAxes.ButtonDownFcn = createCallbackFcn(app, @EnvAxesButtonDown, true);
            end
        end
        
        function setGameOptionsEnableStatus(app, value)
            % Guard against one situation where we try to enable these
            % elements while the game is still running
            if ~(value && ~app.CascadiaGame.GameComplete)
                app.NumberofPlayersSpinner.Enable = value;
                app.GameModeDropDown.Enable = value;
                app.LargestHabitatBonusesCheckBox.Enable = value;
            end
        end
        
        function updateCustomRules(app, newRules)
            app.CustomRules = newRules;         
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            populateGameModeValues(app);
            
            app.CascadiaGame = Game();

            app.NPlayers = app.NumberofPlayersSpinner.Value;

            % Disable chart interactions (pan, zoom, etc.)
            app.CenterAxes.Interactions = [];
            app.EnvAxes.Interactions = [];
        end

        % Button pushed function: NewGameButton
        function beginGame(app, event)
            if app.CascadiaGame.GameComplete % Start Game
                % Hide labels
                for nPlayers = 2:4
                    playerStr = num2str(nPlayers);
                    playerLabelName = ['Player' playerStr 'Label'];
                    tokenLabelName = ['Label_' playerStr];
                    buttonName = ['ViewButton_' playerStr];
    
                    components = {playerLabelName, tokenLabelName, buttonName};
    
                    for i = 1:length(components)
                        if app.NPlayers < nPlayers
                            app.(components{i}).Visible = "off";
                        else
                            app.(components{i}).Visible = "on";
                        end
                    end
                end                       
    
                % Start game object
                app.CascadiaGame = startNewGame(app.CascadiaGame, app.NPlayers,...
                    'GameMode',app.GameModeDropDown.Value,'CustomRules',app.CustomRules,...
                    'HabitatBonus', app.LargestHabitatBonusesCheckBox.Value);
                % This might change if we chose 'random', so we update
                if app.GameModeDropDown.Value == GameModeEnum.RandomRules
                    app.CustomRules = numRules2Let(app, app.CascadiaGame.ScoringRules);
                end
                startNewTurnActions(app);

                % Enable actions
                setGameInteractivity(app, true);  

                % Disable game options
                setGameOptionsEnableStatus(app, false);      

                % Change button text
                event.Source.Text = 'End Game';
            else % End game
                app.CascadiaGame = endGame(app.CascadiaGame);
                updateStatusLabel(app);

                % Re-enable game options
                setGameOptionsEnableStatus(app, true);

                % Disable action buttons
                setGameInteractivity(app, false);

                % Change button text
                event.Source.Text = 'New Game';
            end
        end

        % Value changed function: NumberofPlayersSpinner
        function NumberofPlayersSpinnerValueChanged(app, event)
            app.NPlayers = app.NumberofPlayersSpinner.Value;            
        end

        % Button pushed function: OverpopulationWipeButton
        function OverpopulationWipeButtonPushed(app, event)
            app.CascadiaGame = playerAction(app.CascadiaGame, MovesEnum.OverpopulationWipe, []);

            % Update visuals
            switchView(app, app.CascadiaGame.PlayerTurn);
            app.CenterAxes = drawCenter(app.CascadiaGame, app.CenterAxes, app.HexSideLength);
            updatePlayerPanel(app);
        end

        % Button pushed function: GameRulesButton
        function GameRulesButtonPushed(app, event)
            web(app.RulesPath);
        end

        % Button pushed function: SpendNatureTokenButton
        function SpendNatureTokenButtonPushed(app, event)
            switchView(app, app.CascadiaGame.PlayerTurn);

            % Disable app interactivity
            app.NewGameButton.Enable = false;
            app.ScoringObjectivesButton.Enable = false;
            setGameInteractivity(app, false);

            % Launch child dialog
            app.NatureTokenDlg = NatureTokenDialog(app, app.CenterAxes);
        end

        % Button pushed function: RotateTileButton
        function RotateTileButtonPushed(app, event)
            currPlayer = app.CascadiaGame.Players(app.CascadiaGame.PlayerTurn);

            tileIdx = currPlayer.SelectedTileIdx;
            app.CascadiaGame = playerAction(app.CascadiaGame, MovesEnum.RotateTile, tileIdx);

            switchView(app, app.CascadiaGame.PlayerTurn);
        end

        % Button pushed function: PlaceTileTokenButton
        function PlaceTileTokenButtonPushed(app, event)
            % Switch view if we don't have our view selected - don't want
            % to place without seeing
            if app.CurrEnvView == app.CascadiaGame.PlayerTurn
               prevTurnCount = app.CascadiaGame.TurnCount;
               currPlayer = app.CascadiaGame.Players(app.CascadiaGame.PlayerTurn);
             
               coordinate = currPlayer.Environment.PreviewTile.Coordinate;
             
                if ismember(MovesEnum.PlaceTile, currPlayer.AvailableActions)
                    centerIdx = currPlayer.SelectedTileIdx;
                    move = MovesEnum.PlaceTile;
                else
                    centerIdx = currPlayer.SelectedTokenIdx;
                    move = MovesEnum.PlaceToken;
                end

                metadata = {centerIdx, coordinate};
    
                app.CascadiaGame = playerAction(app.CascadiaGame, move, metadata);   
                
                switchView(app, app.CascadiaGame.PlayerTurn);    
                updatePlayerPanel(app);

                % Since it could be the last move in a turn
                if prevTurnCount ~= app.CascadiaGame.TurnCount || ...
                    app.CascadiaGame.GameComplete
                    startNewTurnActions(app);
                end
            end
        end

        % Button pushed function: DiscardWildlifeTokenButton
        function DiscardWildlifeTokenButtonPushed(app, event)
            prevTurnCount = app.CascadiaGame.TurnCount;
            currPlayerTurn = app.CascadiaGame.PlayerTurn;          
            currPlayer = app.CascadiaGame.Players(currPlayerTurn);

            tokenIdx = currPlayer.SelectedTokenIdx;
            app.CascadiaGame = playerAction(app.CascadiaGame, MovesEnum.DiscardToken, tokenIdx);
            
            switchView(app, currPlayerTurn);
            updatePlayerPanel(app);
            
            % Since it could be the last move in a turn
            if prevTurnCount ~= app.CascadiaGame.TurnCount || ...
                    app.CascadiaGame.GameComplete
                startNewTurnActions(app);
            end
        end

        % Button pushed function: ViewButton, ViewButton_2, ViewButton_3, 
        % ...and 1 other component
        function ViewButtonPushed(app, event)
            playerId = double(string(event.Source.Tag));
            switchView(app, playerId);
        end

        % Button down function: CenterAxes
        function CenterAxesButtonDown(app, event)
            % Determine which tile is clicked
            coords = event.IntersectionPoint;
            ax = event.Source;

            if ~isempty(ax.Children) % If we have something drawn
                axIdx = 0; selectedTile = false; selectedToken = false;
                while axIdx < length(ax.Children) && ~selectedTile && ~selectedToken
                    axIdx = axIdx + 1;
                    currChild = ax.Children(axIdx);
                    if ~isempty(currChild.UserData) && isprop(currChild,"Shape") && isinterior(currChild.Shape, coords(1:2))
                        if (size(currChild.Shape.Vertices,1) == 6) || (size(currChild.Shape.Vertices,1) == 4)
                            selectedTile = true;
                        else
                            selectedToken = true;
                        end
                    end
                end

                currPlayer = app.CascadiaGame.Players(app.CascadiaGame.PlayerTurn);
                canSelectTile = ismember(MovesEnum.SelectTile, currPlayer.AvailableActions);
                canSelectToken = ismember(MovesEnum.SelectToken, currPlayer.AvailableActions);

                if (selectedTile || selectedToken) && (canSelectTile || canSelectToken)
                    newCenterIdx = currChild.UserData;

                    % Clear old borders
                    for i = 1:length(ax.Children)
                        currChild = ax.Children(i);
                        if ~isempty(currChild.UserData)
                            currChild.EdgeColor = ColorEnum.Black.rgbValues;
                        end
                    end

                    % Update player selections
                    if canSelectTile && selectedTile
                        app.CascadiaGame = app.CascadiaGame.playerAction(MovesEnum.SelectTile,newCenterIdx);
                    elseif canSelectToken && selectedToken
                        app.CascadiaGame = app.CascadiaGame.playerAction(MovesEnum.SelectToken,newCenterIdx);
                    end

                    currPlayer = app.CascadiaGame.Players(app.CascadiaGame.PlayerTurn);

                    % Draw selected tile / token border
                    for i = 1:length(ax.Children)
                        currChild = ax.Children(i);

                        isTile = isprop(currChild,"Shape") && (size(currChild.Shape.Vertices,1) == 6 ||...
                            size(currChild.Shape.Vertices,1) == 4);

                        if ~isempty(currChild.UserData)
                            if isTile && (currChild.UserData == currPlayer.SelectedTileIdx)
                                currChild.EdgeColor = ColorEnum.Gold.rgbValues;
                            elseif ~isTile &&  (currChild.UserData == currPlayer.SelectedTokenIdx)
                                currChild.EdgeColor = ColorEnum.Gold.rgbValues;
                            end
                        end
                    end
                end

                switchView(app, app.CascadiaGame.PlayerTurn);
                updatePlayerPanel(app);

            end
        end

        % Button down function: EnvAxes
        function EnvAxesButtonDown(app, event)
            % Just if view == currentPlayer, otherwise do nothing.
            if ~isempty(app.CascadiaGame.PlayerTurn) && (app.CurrEnvView == app.CascadiaGame.PlayerTurn)
                
                % See if we've selected a preview tile 
                cartCoords = event.IntersectionPoint;
                hexCoords = HabitatTile.cart2hex(cartCoords, app.HexSideLength);

                currPlayer = app.CascadiaGame.Players(app.CascadiaGame.PlayerTurn);
                playerEnv = currPlayer.Environment;
                selectedPreviewTile = isPlaceableTileCoord(playerEnv, hexCoords);
                selectedTokenTile = isPlaceableTokenCoord(playerEnv, hexCoords);
                
                if (selectedPreviewTile && currPlayer.SelectedTileIdx ~= 0 && ~currPlayer.TilePlaced) || ...
                        (selectedTokenTile && currPlayer.SelectedTokenIdx ~= 0 && currPlayer.TilePlaced)
                    gameObj = app.CascadiaGame;

                    if ~currPlayer.TilePlaced 
                        % If so, add to environment and update display
                        selectedTile = gameObj.HabitatTiles(gameObj.CenterTileIdx(currPlayer.SelectedTileIdx));
                        selectedTile.Coordinate = hexCoords;
                        playerEnv.PreviewTile = selectedTile;
                    else 
                        % Tile placed already, display token preview instead
                        selectedTile = tileAtCoords(playerEnv, hexCoords);
                        selectedToken = gameObj.WildlifeTokens(gameObj.CenterTokenIdx(currPlayer.SelectedTokenIdx));
                        playerEnv.PreviewTile = selectedTile;
                        playerEnv.PreviewToken = selectedToken;
                    end
                    app.EnvAxes = drawEnvironment(playerEnv, app.EnvAxes, currPlayer, app.HexSideLength);

                    % Update game > player > environment heirarchy 
                    currPlayer.Environment = playerEnv;
                    gameObj.Players(gameObj.PlayerTurn) = currPlayer;
                    app.CascadiaGame = gameObj;
                    updatePlayerPanel(app);
                end
            end
        end

        % Close request function: CascadiaUIFigure
        function CascadiaUIFigureCloseRequest(app, event)
            % Close child dialogs
            delete(app.ResultsDlg);
            delete(app.NatureTokenDlg);
            delete(app.ObjectivesDlg);

            delete(app);            
        end

        % Button pushed function: ScoringObjectivesButton
        function ScoringObjectivesButtonPushed(app, event)
            % Open scoring objectives dlg

            % Disable app interactivity
            setGameOptionsEnableStatus(app, false);
            app.ScoringObjectivesButton.Enable = false;
            app.NewGameButton.Enable = false;
            setGameInteractivity(app,false);
           
            app.ObjectivesDlg = ScoreObjectiveDialog(app, app.GameModeDropDown.Value, app.CustomRules);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.CascadiaUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {611, 611};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {390, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.CascadiaUIFigure = uifigure('Visible', 'off');
            app.CascadiaUIFigure.Name = 'Cascadia';
            app.CascadiaUIFigure.Icon = fullfile(pathToMLAPP, 'Images', 'HexIcon.png');
            app.CascadiaUIFigure.CloseRequestFcn = createCallbackFcn(app, @CascadiaUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.CascadiaUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.CascadiaLabel = uilabel(app.GridLayout2);
            app.CascadiaLabel.Text = 'Cascadia';
            app.NewGameButton = uibutton(app.GridLayout2, 'push');
            app.NewGameButton.ButtonPushedFcn = createCallbackFcn(app, @beginGame, true);
            app.NewGameButton.Text = 'New Game';
            app.OverpopulationWipeButton = uibutton(app.GridLayout2, 'push');
            app.OverpopulationWipeButton.ButtonPushedFcn = createCallbackFcn(app, @OverpopulationWipeButtonPushed, true);
            app.OverpopulationWipeButton.Enable = 'off';
            app.OverpopulationWipeButton.Text = 'Overpopulation Wipe';
            app.PlayerActionsLabel = uilabel(app.GridLayout2);
            app.PlayerActionsLabel.Text = 'Player Actions';
            app.SpendNatureTokenButton = uibutton(app.GridLayout2, 'push');
            app.SpendNatureTokenButton.ButtonPushedFcn = createCallbackFcn(app, @SpendNatureTokenButtonPushed, true);
            app.SpendNatureTokenButton.Enable = 'off';
            app.SpendNatureTokenButton.Text = 'Spend Nature Token';
            app.RotateTileButton = uibutton(app.GridLayout2, 'push');
            app.RotateTileButton.ButtonPushedFcn = createCallbackFcn(app, @RotateTileButtonPushed, true);
            app.RotateTileButton.Enable = 'off';
            app.RotateTileButton.Text = 'Rotate Tile';
            app.PlaceTileTokenButton = uibutton(app.GridLayout2, 'push');
            app.PlaceTileTokenButton.ButtonPushedFcn = createCallbackFcn(app, @PlaceTileTokenButtonPushed, true);
            app.PlaceTileTokenButton.Enable = 'off';
            app.PlaceTileTokenButton.Text = 'Place Tile / Token';
            app.ViewButton = uibutton(app.GridLayout2, 'push');
            app.ViewButton.ButtonPushedFcn = createCallbackFcn(app, @ViewButtonPushed, true);
            app.ViewButton.Tag = '1';
            app.ViewButton.Enable = 'off';
            app.ViewButton.Text = 'View';
            app.ViewButton_2 = uibutton(app.GridLayout2, 'push');
            app.ViewButton_2.ButtonPushedFcn = createCallbackFcn(app, @ViewButtonPushed, true);
            app.ViewButton_2.Tag = '2';
            app.ViewButton_2.Enable = 'off';
            app.ViewButton_2.Text = 'View';
            app.ViewButton_3 = uibutton(app.GridLayout2, 'push');
            app.ViewButton_3.ButtonPushedFcn = createCallbackFcn(app, @ViewButtonPushed, true);
            app.ViewButton_3.Tag = '3';
            app.ViewButton_3.Enable = 'off';
            app.ViewButton_3.Text = 'View';
            app.ViewButton_4 = uibutton(app.GridLayout2, 'push');
            app.ViewButton_4.ButtonPushedFcn = createCallbackFcn(app, @ViewButtonPushed, true);
            app.ViewButton_4.Tag = '4';
            app.ViewButton_4.Enable = 'off';
            app.ViewButton_4.Text = 'View';
            app.Label_1 = uilabel(app.GridLayout2);
            app.Label_1.Text = '0';
            app.Label_2 = uilabel(app.GridLayout2);
            app.Label_2.Text = '0';
            app.Label_3 = uilabel(app.GridLayout2);
            app.Label_3.Text = '0';
            app.Label_4 = uilabel(app.GridLayout2);
            app.Label_4.Text = '0';
            app.Player1Label = uilabel(app.GridLayout2);
            app.Player1Label.Text = 'Player 1';
            app.Player2Label = uilabel(app.GridLayout2);
            app.Player2Label.Text = 'Player 2';
            app.Player3Label = uilabel(app.GridLayout2);
            app.Player3Label.Text = 'Player 3';
            app.Player4Label = uilabel(app.GridLayout2);
            app.Player4Label.Text = 'Player 4';
            app.NatureTokensLabel = uilabel(app.GridLayout2);
            app.NatureTokensLabel.Text = 'Nature Tokens';
            app.GameStatusLabel = uilabel(app.GridLayout2);
            app.GameStatusLabel.Text = 'Game Status:';
            app.StatusLabel = uilabel(app.GridLayout2);
            app.StatusLabel.Text = 'Game not started.';
            app.DiscardWildlifeTokenButton = uibutton(app.GridLayout2, 'push');
            app.DiscardWildlifeTokenButton.ButtonPushedFcn = createCallbackFcn(app, @DiscardWildlifeTokenButtonPushed, true);
            app.DiscardWildlifeTokenButton.Enable = 'off';
            app.DiscardWildlifeTokenButton.Text = 'Discard Wildlife Token';
            app.NumberofPlayersLabel = uilabel(app.GridLayout2);
            app.NumberofPlayersLabel.Text = 'Number of Players:';
            app.NumberofPlayersSpinner = uispinner(app.GridLayout2);
            app.NumberofPlayersSpinner.Limits = [1 4];
            app.NumberofPlayersSpinner.ValueDisplayFormat = '%11.0g';
            app.NumberofPlayersSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofPlayersSpinnerValueChanged, true);
            app.NumberofPlayersSpinner.Value = 2;
            app.PlayerLabel = uilabel(app.GridLayout2);
            app.PlayerLabel.Text = 'Player';
            app.EnvironmentLabel = uilabel(app.GridLayout2);
            app.EnvironmentLabel.Text = 'Environment';
            app.GameRulesButton = uibutton(app.GridLayout2, 'push');
            app.GameRulesButton.ButtonPushedFcn = createCallbackFcn(app, @GameRulesButtonPushed, true);
            app.GameRulesButton.Text = 'Game Rules';
            app.ScoringObjectivesButton = uibutton(app.GridLayout2, 'push');
            app.ScoringObjectivesButton.ButtonPushedFcn = createCallbackFcn(app, @ScoringObjectivesButtonPushed, true);
            app.ScoringObjectivesButton.Text = 'Scoring Objectives';
            app.GameModeDropDownLabel = uilabel(app.GridLayout2);
            app.GameModeDropDownLabel.Text = 'Game Mode:';
            app.GameModeDropDown = uidropdown(app.GridLayout2);
            app.GameModeDropDown.Items = {};
            app.GameModeDropDown.Value = {};
            app.LargestHabitatBonusesCheckBox = uicheckbox(app.GridLayout2);
            app.LargestHabitatBonusesCheckBox.Text = 'Largest Habitat Bonuses';
            app.LargestHabitatBonusesCheckBox.Value = true;
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.EnvAxes = uiaxes(app.GridLayout3);
            app.EnvAxes.XLimitMethod = 'padded';
            app.EnvAxes.YLimitMethod = 'padded';
            app.EnvAxes.ZLimitMethod = 'padded';
            app.EnvAxes.XTick = [];
            app.EnvAxes.YTick = [];
            app.EnvAxes.ButtonDownFcn = createCallbackFcn(app, @EnvAxesButtonDown, true);
            app.CenterAxes = uiaxes(app.GridLayout3);
            app.CenterAxes.XLim = [0 10];
            app.CenterAxes.YLim = [-3 3];
            app.CenterAxes.XLimitMethod = 'tight';
            app.CenterAxes.YLimitMethod = 'tight';
            app.CenterAxes.ZLimitMethod = 'tight';
            app.CenterAxes.XTick = [];
            app.CenterAxes.YTick = [];
            app.CenterAxes.ButtonDownFcn = createCallbackFcn(app, @CenterAxesButtonDown, true);
            % Show the figure after all components are created
            app.CascadiaUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CascadiaGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CascadiaUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CascadiaUIFigure)
        end
    end
end