classdef NatureTokenDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpendNatureTokenUIFigure    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        CancelButton                matlab.ui.control.Button
        ConfirmChoicesButton        matlab.ui.control.Button
        NatureTokenModeButtonGroup  matlab.ui.container.ButtonGroup
        DecoupleButton              matlab.ui.control.RadioButton
        CustomWipeButton            matlab.ui.control.RadioButton
        CenterAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        MainApp % Description
        NatureTokenMode = 1; % 1 - Decouple tile/token, 2 - Custom wipe
        TokensToWipe
    end
    
    methods (Access = private)
        
        function resetSelections(app)
            % Resets all the center tile/token outlines to black
            ax = app.CenterAxes;
            axIdx = 0;
            while axIdx < length(ax.Children)
                axIdx = axIdx + 1;
                currChild = ax.Children(axIdx);
                if isprop(currChild, 'EdgeColor')
                    currChild.EdgeColor = ColorEnum.Black.rgbValues;
                end
            end
        end
        
        function closeDialog(app)
            % Re-enable app interactivity 
            if isvalid(app.MainApp)
                app.MainApp.NewGameButton.Enable = true;
                app.MainApp.ScoringObjectivesButton.Enable = true;
                setGameInteractivity(app.MainApp,true);
            end

            delete(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, centerAxes)
            app.MainApp = mainApp;

            % Set location same as parent dialog
            app.SpendNatureTokenUIFigure.Position(1:2) = mainApp.CascadiaUIFigure.Position(1:2);

            % Delete placeholder axes & copy existing center
            delete(app.CenterAxes);
            app.CenterAxes = copyobj(centerAxes, app.GridLayout);
            app.CenterAxes.Toolbar.Visible = 'off';
            app.CenterAxes.PlotBoxAspectRatio = [10 6 1];
            app.CenterAxes.XLim = [0 10];
            app.CenterAxes.YLim = [-3 3];
            app.CenterAxes.XLimitMethod = 'tight';
            app.CenterAxes.YLimitMethod = 'tight';
            app.CenterAxes.ZLimitMethod = 'tight';
            app.CenterAxes.XTick = [];
            app.CenterAxes.YTick = [];
            app.CenterAxes.Box = 'on';
            app.CenterAxes.NextPlot = 'add';
            app.CenterAxes.Layout.Row = 3;
            app.CenterAxes.Layout.Column = [2 3];
            app.CenterAxes.ButtonDownFcn = createCallbackFcn(app, @CenterAxesButtonDown, true);
            app.CenterAxes.PickableParts = 'all';

            % Assign user data to radio buttons
            app.DecoupleButton.UserData = 1;
            app.CustomWipeButton.UserData = 2;

            resetSelections(app);
        end

        % Button down function: CenterAxes
        function CenterAxesButtonDown(app, event)
            % Determine which token is clicked
            coords = event.IntersectionPoint;
            ax = event.Source;

            axIdx = 0; selectedTile = false; selectedToken = false;
            while axIdx < length(ax.Children) && ~selectedTile && ~selectedToken
                axIdx = axIdx + 1;
                currChild = ax.Children(axIdx);
                if ~isempty(currChild.UserData) && isprop(currChild,"Shape") && isinterior(currChild.Shape, coords(1:2))
                    if (size(currChild.Shape.Vertices,1) == 6) || (size(currChild.Shape.Vertices,1) == 4)
                        selectedTile = true;
                    else
                        selectedToken = true;
                    end
                end
            end

            if selectedToken && (app.NatureTokenMode == 2)
                % Flip border color and update selections         
                if all(currChild.EdgeColor == ColorEnum.Black.rgbValues)
                    currChild.EdgeColor = ColorEnum.Red.rgbValues;
                    app.TokensToWipe = [app.TokensToWipe currChild.UserData];
                else
                    currChild.EdgeColor = ColorEnum.Black.rgbValues;
                    app.TokensToWipe(app.TokensToWipe == currChild.UserData) = [];
                end
            end
        end

        % Selection changed function: NatureTokenModeButtonGroup
        function NatureTokenModeButtonGroupSelectionChanged(app, event)
            selectedButton = app.NatureTokenModeButtonGroup.SelectedObject;
            app.NatureTokenMode = selectedButton.UserData;
        end

        % Callback function: CancelButton, SpendNatureTokenUIFigure
        function SpendNatureTokenUIFigureCloseRequest(app, event)
            closeDialog(app);         
        end

        % Button pushed function: ConfirmChoicesButton
        function ConfirmChoicesButtonPushed(app, event)
            % Confirm spend nature token
            spendNatureToken(app.MainApp, {app.NatureTokenMode, app.TokensToWipe});

            % Close dialog
            closeDialog(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.SpendNatureTokenUIFigure = uifigure('Visible', 'off');
            app.SpendNatureTokenUIFigure.Name = 'Spend Nature Token ';
            app.SpendNatureTokenUIFigure.Icon = fullfile(pathToMLAPP, 'Images', 'HexIcon.png');
            app.SpendNatureTokenUIFigure.CloseRequestFcn = createCallbackFcn(app, @SpendNatureTokenUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.SpendNatureTokenUIFigure);
            app.CenterAxes = uiaxes(app.GridLayout);
            app.CenterAxes.XLim = [0 10];
            app.CenterAxes.YLim = [-3 3];
            app.CenterAxes.XLimitMethod = 'tight';
            app.CenterAxes.YLimitMethod = 'tight';
            app.CenterAxes.ZLimitMethod = 'tight';
            app.CenterAxes.XTick = [];
            app.CenterAxes.YTick = [];
            app.CenterAxes.ButtonDownFcn = createCallbackFcn(app, @CenterAxesButtonDown, true);
            app.NatureTokenModeButtonGroup = uibuttongroup(app.GridLayout);
            app.NatureTokenModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NatureTokenModeButtonGroupSelectionChanged, true);
            app.NatureTokenModeButtonGroup.Title = 'Select Nature Token Mode:';
            app.CustomWipeButton = uiradiobutton(app.NatureTokenModeButtonGroup);
            app.CustomWipeButton.Text = 'Wipe any tokens (Select Tokens Below)';
            app.DecoupleButton = uiradiobutton(app.NatureTokenModeButtonGroup);
            app.DecoupleButton.Text = 'Select Any Tile and Token';
            app.DecoupleButton.Value = true;
            app.ConfirmChoicesButton = uibutton(app.GridLayout, 'push');
            app.ConfirmChoicesButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmChoicesButtonPushed, true);
            app.ConfirmChoicesButton.Text = 'Confirm Choices';
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @SpendNatureTokenUIFigureCloseRequest, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.SpendNatureTokenUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NatureTokenDialog(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SpendNatureTokenUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.SpendNatureTokenUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpendNatureTokenUIFigure)
        end
    end
end