classdef ScoreObjectiveDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WildlifeScoringObjectivesUIFigure  matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        RuleSettingsPanel    matlab.ui.container.Panel
        GridLayout2          matlab.ui.container.GridLayout
        FoxDropDown          matlab.ui.control.DropDown
        FoxDropDownLabel     matlab.ui.control.Label
        HawkDropDown         matlab.ui.control.DropDown
        HawkDropDownLabel    matlab.ui.control.Label
        SalmonDropDown       matlab.ui.control.DropDown
        SalmonDropDownLabel  matlab.ui.control.Label
        ElkDropDown          matlab.ui.control.DropDown
        ElkDropDownLabel     matlab.ui.control.Label
        BearDropDown         matlab.ui.control.DropDown
        BearDropDownLabel    matlab.ui.control.Label
        HawkImage            matlab.ui.control.Image
        BearImage            matlab.ui.control.Image
        SalmonImage          matlab.ui.control.Image
        FoxImage             matlab.ui.control.Image
        ElkImage             matlab.ui.control.Image
    end


    properties (Access = private)
        MainApp
        GameMode
        ScoringRules
    end
    
    properties (Access = public)
        VariantImage         matlab.ui.control.Image 
    end
    
    methods (Access = private)
        
        function syncImageToDropdown(app)            
            if isempty(app.GameMode) || (app.GameMode ~= GameModeEnum.FamilyVariant && ...
                    app.GameMode ~= GameModeEnum.IntermediateVariant)
                % Assign user data to link drop-down menu to image
                app.BearImage.ImageSource = ['Images/BearRule', app.BearDropDown.Value, '.png'];
                app.ElkImage.ImageSource = ['Images/ElkRule', app.ElkDropDown.Value, '.png'];
                app.SalmonImage.ImageSource = ['Images/SalmonRule', app.SalmonDropDown.Value, '.png'];
                app.HawkImage.ImageSource = ['Images/HawkRule', app.HawkDropDown.Value, '.png'];
                app.FoxImage.ImageSource = ['Images/FoxRule', app.FoxDropDown.Value, '.png'];
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, gameMode, scoringRules)
            app.MainApp = mainApp;
            app.GameMode = gameMode;
            app.ScoringRules = scoringRules;

            % This is a bit flaky but oh well. 
            gameInProgress = strcmp(mainApp.NewGameButton.Text,'End Game');

            dropDownArray = [app.BearDropDown, app.ElkDropDown, app.SalmonDropDown, ...
                app.HawkDropDown, app.FoxDropDown];

            switch gameMode
                case GameModeEnum.RandomRules
                    for i = 1:length(dropDownArray)
                        if isempty(scoringRules)
                            dropDownArray(i).Items = {'TBD'};
                        else
                            dropDownArray(i).Items = scoringRules(i);
                        end
                    end
                    syncImageToDropdown(app);
                case {GameModeEnum.FamilyVariant, GameModeEnum.IntermediateVariant}

                    if gameMode == GameModeEnum.FamilyVariant
                        imageTitle = 'FamilyVariant.png';
                    else
                        imageTitle = 'IntermediateVariant.jpg';
                    end

                    % Mess with App Images
                    delete(app.BearImage);
                    delete(app.ElkImage);
                    delete(app.SalmonImage);
                    delete(app.HawkImage);
                    delete(app.FoxImage);

                    % Create Image
                    % Get the file path for locating images
                    pathToMLAPP = fileparts(mfilename('fullpath'));
                    app.VariantImage = uiimage(app.GridLayout);
                    app.VariantImage.Layout.Row = [2 5];
                    app.VariantImage.Layout.Column = [2 6];
                    app.VariantImage.ImageSource = fullfile(pathToMLAPP, 'Images', imageTitle);

                case GameModeEnum.CustomRules
                    for i = 1:length(dropDownArray)
                        dropDownArray(i).UserData = i;
                        if ~gameInProgress
                            dropDownArray(i).Enable = true;
                        end
                        if ~isempty(scoringRules)
                            dropDownArray(i).Value = scoringRules(i);
                        end
                    end
                    syncImageToDropdown(app);
            end       

            % Set location same as parent dialog
            app.WildlifeScoringObjectivesUIFigure.Position(1:2) = mainApp.CascadiaUIFigure.Position(1:2);
        end

        % Close request function: WildlifeScoringObjectivesUIFigure
        function WildlifeScoringObjectivesUIFigureCloseRequest(app, event)
            % Re-enable main app if this is only dialog open
            if isvalid(app.MainApp) && ...
                (isempty(app.MainApp.ResultsDlg) || ~isvalid(app.MainApp.ResultsDlg))
                app.MainApp.ScoringObjectivesButton.Enable = true;
                app.MainApp.NewGameButton.Enable = true;
                setGameOptionsEnableStatus(app.MainApp, true);
                setGameInteractivity(app.MainApp, true);
            end

            % Tell main app custom scoring rules if applicable
            if app.GameMode == GameModeEnum.CustomRules
                updateCustomRules(app.MainApp, app.ScoringRules);
            end

            delete(app);
        end

        % Value changed function: BearDropDown, ElkDropDown, FoxDropDown, 
        % ...and 2 other components
        function DropDownValueChanged(app, event)
            value = event.Source.Value;
            idx = event.Source.UserData;

            % Update scoring rules to match dropdown value
            app.ScoringRules(idx) = value;

            syncImageToDropdown(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.WildlifeScoringObjectivesUIFigure = uifigure('Visible', 'off');
            app.WildlifeScoringObjectivesUIFigure.Name = 'Wildlife Scoring Objectives';
            app.WildlifeScoringObjectivesUIFigure.Icon = fullfile(pathToMLAPP, 'Images', 'HexIcon.png');
            app.WildlifeScoringObjectivesUIFigure.CloseRequestFcn = createCallbackFcn(app, @WildlifeScoringObjectivesUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.WildlifeScoringObjectivesUIFigure);
            app.ElkImage = uiimage(app.GridLayout);
            app.ElkImage.ImageSource = fullfile(pathToMLAPP, 'Images', 'ElkRuleA.png');
            app.FoxImage = uiimage(app.GridLayout);
            app.FoxImage.ImageSource = fullfile(pathToMLAPP, 'Images', 'FoxRuleA.png');
            app.SalmonImage = uiimage(app.GridLayout);
            app.SalmonImage.ImageSource = fullfile(pathToMLAPP, 'Images', 'SalmonRuleA.png');
            app.BearImage = uiimage(app.GridLayout);
            app.BearImage.ImageSource = fullfile(pathToMLAPP, 'Images', 'BearRuleA.png');
            app.HawkImage = uiimage(app.GridLayout);
            app.HawkImage.ImageSource = fullfile(pathToMLAPP, 'Images', 'HawkRuleA.png');
            app.RuleSettingsPanel = uipanel(app.GridLayout);
            app.RuleSettingsPanel.Title = 'Rule Settings';
            app.GridLayout2 = uigridlayout(app.RuleSettingsPanel);
            app.BearDropDownLabel = uilabel(app.GridLayout2);
            app.BearDropDownLabel.Enable = 'off';
            app.BearDropDownLabel.Text = 'Bear';
            app.BearDropDown = uidropdown(app.GridLayout2);
            app.BearDropDown.Items = {'A', 'B', 'C', 'D'};
            app.BearDropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.BearDropDown.Enable = 'off';
            app.BearDropDown.Value = 'A';
            app.ElkDropDownLabel = uilabel(app.GridLayout2);
            app.ElkDropDownLabel.Enable = 'off';
            app.ElkDropDownLabel.Text = 'Elk';
            app.ElkDropDown = uidropdown(app.GridLayout2);
            app.ElkDropDown.Items = {'A', 'B', 'C', 'D'};
            app.ElkDropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.ElkDropDown.Enable = 'off';
            app.ElkDropDown.Value = 'A';
            app.SalmonDropDownLabel = uilabel(app.GridLayout2);
            app.SalmonDropDownLabel.Enable = 'off';
            app.SalmonDropDownLabel.Text = 'Salmon';
            app.SalmonDropDown = uidropdown(app.GridLayout2);
            app.SalmonDropDown.Items = {'A', 'B', 'C', 'D'};
            app.SalmonDropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.SalmonDropDown.Enable = 'off';
            app.SalmonDropDown.Value = 'A';
            app.HawkDropDownLabel = uilabel(app.GridLayout2);
            app.HawkDropDownLabel.Enable = 'off';
            app.HawkDropDownLabel.Text = 'Hawk';
            app.HawkDropDown = uidropdown(app.GridLayout2);
            app.HawkDropDown.Items = {'A', 'B', 'C', 'D'};
            app.HawkDropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.HawkDropDown.Enable = 'off';
            app.HawkDropDown.Value = 'A';
            app.FoxDropDownLabel = uilabel(app.GridLayout2);
            app.FoxDropDownLabel.Enable = 'off';
            app.FoxDropDownLabel.Text = 'Fox';
            app.FoxDropDown = uidropdown(app.GridLayout2);
            app.FoxDropDown.Items = {'A', 'B', 'C', 'D'};
            app.FoxDropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.FoxDropDown.Enable = 'off';
            app.FoxDropDown.Value = 'A';
            % Show the figure after all components are created
            app.WildlifeScoringObjectivesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ScoreObjectiveDialog(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.WildlifeScoringObjectivesUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.WildlifeScoringObjectivesUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WildlifeScoringObjectivesUIFigure)
        end
    end
end