classdef ScoreTableDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GameResultsUIFigure  matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        CloseButton          matlab.ui.control.Button
        WinnerLabel          matlab.ui.control.Label
        FinalScoresLabel     matlab.ui.control.Label
        UITable              matlab.ui.control.Table
    end

    properties (Access = private)
        MainApp % Parent app
    end

    methods (Access = private)
        function rowNum = findRowsThatContain(~, table, searchChars)
            rowNum = find(cellfun(@(x) contains(x,searchChars), table.Row));
            if isempty(rowNum)
                error('Could not find row ''%s'' in score table!\n', searchChars);
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, scoreTable)
            app.MainApp = mainApp;

            app.UITable = uitable(app.GridLayout, 'Data', scoreTable);
            app.UITable.Layout.Row = 2;
            app.UITable.Layout.Column = [2 3];

            if ~isempty(scoreTable)
                % Find and highlight total columns
                totalRows = findRowsThatContain(app, scoreTable, 'Total');
                s = uistyle("BackgroundColor",[0.3 0.6 0.3]);
                addStyle(app.UITable,s,"row",totalRows);

                % Populate winner label
                totalScores = table2array(scoreTable(totalRows(end),:));
                winnerIdx = (totalScores == max(totalScores));
                winnerNames = scoreTable.Properties.VariableNames(winnerIdx);
                nTies = nnz(winnerIdx) - 1;
                switch nTies
                    case 0 % One winner
                        app.WinnerLabel.Text = [app.WinnerLabel.Text, ' ', winnerNames{1}, '!'];
                    case 1 % Two winners
                        app.WinnerLabel.Text = [app.WinnerLabel.Text, ' It''s a tie between ', ...
                            winnerNames{1}, ' and ', winnerNames{2}, '!'];
                    otherwise % Multiple winners
                        app.WinnerLabel.Text = [app.WinnerLabel.Text, ' It''s a tie between '];
                        for i = 1:length(winnerNames)
                            if i < length(winnerNames)
                                app.WinnerLabel.Text = [app.WinnerLabel.Text, winnerNames{i}, ', '];
                            else
                                app.WinnerLabel.Text = [app.WinnerLabel.Text, 'and ', winnerNames{i}, '!'];
                            end
                        end
                end
            end
        end

        % Callback function: CloseButton, GameResultsUIFigure
        function GameResultsUIFigureCloseRequest(app, event)
            % Re-enable main app
            if isvalid(app.MainApp)
                app.MainApp.NumberofPlayersSpinner.Enable = true;
                app.MainApp.GameModeDropDown.Enable = true;
                app.MainApp.LargestHabitatBonusesCheckBox.Enable = true;
                app.MainApp.NewGameButton.Enable = true;
            end

            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.GameResultsUIFigure = uifigure('Visible', 'off');
            app.GameResultsUIFigure.Name = 'Game Results';
            app.GameResultsUIFigure.Icon = fullfile(pathToMLAPP, 'HexIcon.png');
            app.GameResultsUIFigure.CloseRequestFcn = createCallbackFcn(app, @GameResultsUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.GameResultsUIFigure);
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.FinalScoresLabel = uilabel(app.GridLayout);
            app.FinalScoresLabel.Text = 'Final Scores';
            app.WinnerLabel = uilabel(app.GridLayout);
            app.WinnerLabel.Text = 'Winner: ';
            app.CloseButton = uibutton(app.GridLayout, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @GameResultsUIFigureCloseRequest, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.GameResultsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ScoreTableDialog(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.GameResultsUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.GameResultsUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GameResultsUIFigure)
        end
    end
end